classdef iTough < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        TabGroup     matlab.ui.container.TabGroup
        Tab          matlab.ui.container.Tab
        HTML         matlab.ui.control.HTML
        Tab2         matlab.ui.container.Tab
        StartButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        t        % 定时器对象
        updateImageFlag = false    % 是否更新图像的标志
        ha1% = animatedline(app.UIAxes,'DisplayName', 'hand_1', 'Color', 'b', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);
        hi1% = animatedline(app.UIAxes,'DisplayName', 'index_1', 'Color', 'r', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);
        hi2% = animatedline(app.UIAxes,'DisplayName', 'index_2', 'Color', 'g', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);

    end
    
    
    methods (Access = private)
    % 创建并初始化定时器对象
    function createTimer(app)
        app.t = timer;
        app.t.ExecutionMode = 'fixedRate';   % 计时器执行模式为固定速率
        app.t.Period = 0.001;                  % 更新间隔为 0.1 秒
        app.t.TimerFcn = @(~, ~) updateImage(app);

%         app.ax = gca;
        app.ha1 = animatedline(app.UIAxes,'DisplayName', 'hand_1', 'Color', 'b', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);
        app.hi1 = animatedline(app.UIAxes,'DisplayName', 'index_1', 'Color', 'r', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);
        app.hi2 = animatedline(app.UIAxes,'DisplayName', 'index_2', 'Color', 'g', 'LineWidth', 4, 'Marker', 'd', 'MarkerSize', 9);
    end
end

methods (Access = private)
    % App 创建函数
    function create(app)
        % 创建定时器对象
        createTimer(app);

        % 创建并配置 UI 组件
        app.StartButton.ButtonPushedFcn = @(~, ~) StartButtonPushed(app);
    end
end
methods (Access = private)
    function Func_ShowIndexFinger(~,buffer,hx,hy,hz)
    %% 函数是对于单指进行姿态输出的，
    % 输入变量：
    %       buffer：单指三骨节的姿态
    %       hx，hy，hz：绘制骨节的句柄
    % 输出变量：
    % 
    % 作者：马永伟
    %% 执行开始 预设 %LeftOrRight为0则表示左手
        q_hand = buffer(1:1*4)./norm(buffer(1:1*4));
        q_i1 = buffer(1*4+1:2*4)./norm(buffer(1*4+1:2*4));
        q_i2 = buffer(2*4+1:3*4)./norm(buffer(2*4+1:3*4));
        % Demo style: 1) tri-axis, or 2) individual vector, or 3) tri-axis + box
        Hand_i = -[0;0.6;0];
        Index_Finger_1 = -[0;0.6;0];
        Index_Finger_2 = -[0;0.6;0];
        %% 姿态变化
    %     Thumb_Finger_1 = [0.5;0;0];
        % rotate the tri-axis
        % 食指
        Hand_i = quatrotate(q_hand, Hand_i')';
        Index_Finger_1 = quatrotate(q_i1, Index_Finger_1')' + Hand_i;
        Index_Finger_2 = quatrotate(q_i2, Index_Finger_2')' + Index_Finger_1;
        
        %% 输出展示
        % redraw 食指
        clearpoints(hx);
        addpoints(hx, 0, 0, 0);
        addpoints(hx, Hand_i(1), Hand_i(2), Hand_i(3));
        clearpoints(hy);
        addpoints(hy, Hand_i(1), Hand_i(2), Hand_i(3));
        addpoints(hy, Index_Finger_1(1), Index_Finger_1(2), Index_Finger_1(3));
        clearpoints(hz);
        addpoints(hz, Index_Finger_1(1), Index_Finger_1(2), Index_Finger_1(3));
        addpoints(hz, Index_Finger_2(1), Index_Finger_2(2), Index_Finger_2(3));
        % with 'limitrate nocallbacks' will improve performance dramatically.
        drawnow limitrate nocallbacks
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            view(app.UIAxes,[30,30,10]);
            xlim(app.UIAxes, [-2, 2]);
            ylim(app.UIAxes, [-2, 2]);
            zlim(app.UIAxes, [-2, 2]);
            grid(app.UIAxes, 'on');
            create(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            if app.updateImageFlag
                % 如果正在更新图像，则停止定时器，并修改按钮文本
                stop(app.t);
                app.StartButton.Text = '开始更新';
            else
                % 如果未在更新图像，则开始定时器，并修改按钮文本
                start(app.t);
                app.StartButton.Text = '暂停更新';
            end
            app.updateImageFlag = ~app.updateImageFlag;
        end
    
        % 更新指纹图像的回调函数
        function updateImage(app, ~)
            % 在这里编写更新指纹图像的代码
            % 示例：随机生成一个 256x256 的灰度图像
            mtx = [];
            for i = 1:12
                mtx(end+1) = rand();
            end
            app.Func_ShowIndexFinger(mtx,app.ha1,app.hi1,app.hi2)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.HTML = uihtml(app.Tab);
            app.HTML.HTMLSource = 'G:\myw\code\GitCodeOf-hand-finger-attitude-recognition-tracking202112\hand-finger-attitude-recognition-tracking202112\matlabGuiDesigner\LineChart.html';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes = uiaxes(app.Tab2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.Tab2, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = '开始';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iTough

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end